classdef Rehaussement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRehaussement                 matlab.ui.Figure
        RhaussementdimagesLabel        matlab.ui.control.Label
        UploadImageButton              matlab.ui.control.Button
        ShoworiginalhistogrammeButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        RecadragededynamiqueButton     matlab.ui.control.Button
        InversiondedynamiqueButton     matlab.ui.control.Button
        EgalisationdhistogrammeButton  matlab.ui.control.Button
        EclaircissementButton          matlab.ui.control.Button
        AjustementdecontrasteButton    matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        ClearButton                    matlab.ui.control.Button
        RetourButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        imageReh % Description
        App % Description
    end


    methods (Access = private)

        % Button pushed function: UploadImageButton
        function upload_image_Callback(app, event)
             % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes);  % Assuming UIAxes is the name of your UIAxes component
            app.imageReh = image;
        end

        % Button pushed function: ShoworiginalhistogrammeButton
        function drawHistogramme(app, event)
            % Vérifier si une image est chargée
            if isempty(app.imageReh)
                msgbox('Veuillez d''abord télécharger une image.', 'Erreur', 'error');
                return;
            end
        
            % Convertir l'image en niveaux de gris si elle est en couleur
            if size(app.imageReh, 3) == 3
                imageEnNiveauxDeGris = rgb2gray(app.imageReh);
            else
                imageEnNiveauxDeGris = app.imageReh;
            end
            % Calculer l'histogramme de l'image
            [levels, histogram] = imhist(imageEnNiveauxDeGris);
            % Dessiner l'histogramme
            area(app.UIAxes2, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes2, 'Histogramme de l''image d''origine');
            xlabel(app.UIAxes2, 'Niveau de gris');
            ylabel(app.UIAxes2, 'Fréquence');
        end

        % Button pushed function: RecadragededynamiqueButton
        function dynamicRange_Callback(app, event)
            % Vérifiez si une image est chargée
            if isempty(app.imageReh)
                msgbox('Veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Supposons que vous souhaitez recadrer entre 20% et 80% de la dynamique
            lowerPercentage = 20;
            upperPercentage = 80;
            % Convertir l'image en niveaux de gris
            imageGrayscale = rgb2gray(app.imageReh);
            % Calculer les valeurs de recadrage
            dynamicRange = stretchlim(imageGrayscale, [lowerPercentage / 100, upperPercentage / 100]);
            % Appliquer le recadrage de la dynamique
            imageModified = imadjust(imageGrayscale, dynamicRange, []);
            % Afficher l'image modifiée sur UIAxes4
            imshow(imageModified, 'Parent', app.UIAxes4);
            % Afficher l'histogramme de l'image modifiée sur UIAxes3
            [levels, histogram] = imhist(imageModified);
            area(app.UIAxes3, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes3, 'histogramme Image avec recadrage de dynamique');

        end

        % Button pushed function: InversiondedynamiqueButton
        function inverseDynamicRange_Callback(app, event)
            % Vérifiez si une image est chargée
            if isempty(app.imageReh)
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Supposons que vous souhaitez inverser la dynamique de l'image
            lowerPercentage = 20;
            upperPercentage = 80;
            % Convertir l'image en niveaux de gris
            imageGrayscale = rgb2gray(app.imageReh);
            % Calculer les valeurs de recadrage
            dynamicRange = stretchlim(imageGrayscale, [lowerPercentage / 100, upperPercentage / 100]);
            % Inverser la dynamique
            inverseDynamicRange = [1 - dynamicRange(2), 1 - dynamicRange(1)];
            % Appliquer l'inversion de la dynamique
            imageModified = imadjust(imageGrayscale, inverseDynamicRange, []);
            % Afficher l'image modifiée sur UIAxes4
            imshow(imageModified, 'Parent', app.UIAxes4);
            % Afficher l'histogramme de l'image modifiée sur UIAxes3
            [levels, histogram] = imhist(imageModified);
            area(app.UIAxes3, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes3, 'histogramme Image avec inversion de dynamique');

        end

        % Button pushed function: EgalisationdhistogrammeButton
        function histogramEqualization_Callback(app, event)
            % Vérifiez si une image est chargée
            if isempty(app.imageReh)
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Convertir l'image en niveaux de gris
            imageGrayscale = rgb2gray(app.imageReh);
            % Appliquer l'égalisation d'histogramme
            equalizedImage = histeq(imageGrayscale);
            % Afficher l'image égalisée sur UIAxes4
            imshow(equalizedImage, 'Parent', app.UIAxes4);
            title(app.UIAxes4, 'Image égalisée');
            % Afficher l'histogramme de l'image égalisée sur UIAxes3
            [levels, histogram] = imhist(equalizedImage);
            area(app.UIAxes3, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes3, 'histogramme Image égalisée');
        end

        % Button pushed function: EclaircissementButton
        function brightenImage_Callback(app, event)
            % Vérifiez si une image est chargée
            if isempty(app.imageReh)
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            imageGrayscale = rgb2gray(app.imageReh);
            brightenedImage = imadjust(imageGrayscale);            % Afficher l'image éclaircie sur UIAxes4
            imshow(brightenedImage, 'Parent', app.UIAxes4);
            title(app.UIAxes4, 'Image éclaircie');
            % Afficher l'histogramme de l'image éclaircie sur UIAxes3
            [levels, histogram] = imhist(brightenedImage);
            area(app.UIAxes3, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes3, 'Histogramme Image éclaircie');
        end

        % Button pushed function: AjustementdecontrasteButton
        function contrastAdjustment_Callback(app, event)
            % Vérifiez si une image est chargée
            if isempty(app.imageReh)
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Convertir l'image en niveaux de gris
            imageGrayscale = rgb2gray(app.imageReh);
            % Ajuster le contraste
            contrastAdjustedImage = imadjust(imageGrayscale);
            % Afficher l'image originale sur UIAxes4
            imshow(imageGrayscale, 'Parent', app.UIAxes4);
            title(app.UIAxes4, 'Image originale');
            % Afficher l'image avec contraste ajusté sur UIAxes5
            imshow(contrastAdjustedImage, 'Parent', app.UIAxes4);
            title(app.UIAxes4, 'Image ajustée au contraste');
            % Afficher l'histogramme de l'image avec contraste ajusté sur UIAxes3
            [levels, histogram] = imhist(contrastAdjustedImage);
            area(app.UIAxes3, levels, histogram, 'FaceColor', 'blue', 'EdgeColor', 'none');
            title(app.UIAxes3, 'Histogramme Image ajustée au contraste');
        end

        % Button pushed function: ClearButton
        function clear_images_Callback(app, event)
            % Reset the imgOriginal property to empty
            app.imageReh = [];
            % Clear all axes components
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            % Optionally, you may want to display a message to inform the user
            msgbox('Images effacées.', 'Info', 'info');
        end

        % Button pushed function: RetourButton
        function Return_Callback(app, event)
            app.App = app1;
            app.App.UIApplication.Visible = 'on';  % Show the UIFigure of the Traitement app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRehaussement = uifigure;
            app.UIRehaussement.Name = 'Rehaussement';
            app.RhaussementdimagesLabel = uilabel(app.UIRehaussement);
            app.RhaussementdimagesLabel.Text = 'Réhaussement d''images';
            app.UploadImageButton = uibutton(app.UIRehaussement, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @upload_image_Callback, true);
            app.UploadImageButton.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.UploadImageButton.Text = 'Upload Image';
            app.ShoworiginalhistogrammeButton = uibutton(app.UIRehaussement, 'push');
            app.ShoworiginalhistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @drawHistogramme, true);
            app.ShoworiginalhistogrammeButton.Text = 'Show original histogramme';
            app.UIAxes = uiaxes(app.UIRehaussement);
            title(app.UIAxes, 'Image Originale')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIRehaussement);
            title(app.UIAxes2, 'Histogramme originale')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RecadragededynamiqueButton = uibutton(app.UIRehaussement, 'push');
            app.RecadragededynamiqueButton.ButtonPushedFcn = createCallbackFcn(app, @dynamicRange_Callback, true);
            app.RecadragededynamiqueButton.Text = 'Recadrage de dynamique';
            app.InversiondedynamiqueButton = uibutton(app.UIRehaussement, 'push');
            app.InversiondedynamiqueButton.ButtonPushedFcn = createCallbackFcn(app, @inverseDynamicRange_Callback, true);
            app.InversiondedynamiqueButton.Text = 'Inversion de dynamique';
            app.EgalisationdhistogrammeButton = uibutton(app.UIRehaussement, 'push');
            app.EgalisationdhistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @histogramEqualization_Callback, true);
            app.EgalisationdhistogrammeButton.Text = 'Egalisation d''histogramme';
            app.EclaircissementButton = uibutton(app.UIRehaussement, 'push');
            app.EclaircissementButton.ButtonPushedFcn = createCallbackFcn(app, @brightenImage_Callback, true);
            app.EclaircissementButton.Text = 'Eclaircissement';
            app.AjustementdecontrasteButton = uibutton(app.UIRehaussement, 'push');
            app.AjustementdecontrasteButton.ButtonPushedFcn = createCallbackFcn(app, @contrastAdjustment_Callback, true);
            app.AjustementdecontrasteButton.Text = 'Ajustement de contraste';
            app.UIAxes3 = uiaxes(app.UIRehaussement);
            title(app.UIAxes3, 'Histogramme rehaussée')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIRehaussement);
            title(app.UIAxes4, 'Image Rehaussée')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = {'[ ]'};
            app.ClearButton = uibutton(app.UIRehaussement, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear_images_Callback, true);
            app.ClearButton.Text = 'Clear';
            app.RetourButton = uibutton(app.UIRehaussement, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @Return_Callback, true);
            app.RetourButton.Text = 'Retour';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Rehaussement

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRehaussement)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRehaussement)
        end
    end
end