classdef Restauration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIRestauration           matlab.ui.Figure
        RstaurationdimagesLabel  matlab.ui.control.Label
        ImagesRGBLabel           matlab.ui.control.Label
        UploadimageButton        matlab.ui.control.Button
        FiltregaussienButton     matlab.ui.control.Button
        FiltreMedianButton       matlab.ui.control.Button
        FiltremoyenneurButton    matlab.ui.control.Button
        FiltreMINButton          matlab.ui.control.Button
        FiltreMAXButton          matlab.ui.control.Button
        PSNREditFieldLabel       matlab.ui.control.Label
        PSNREditField            matlab.ui.control.NumericEditField
        UIAxes1                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        TypesBruitListBoxLabel   matlab.ui.control.Label
        TypesBruitListBox        matlab.ui.control.ListBox
        addNoiseButton           matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        RetourButton             matlab.ui.control.Button
    end


    properties (Access = private)
        imageR % Description
        noisyImageStored % Description
        App % Description
    end


    methods (Access = private)

        % Button pushed function: UploadimageButton
        function upload_image_Callback(app, event)
             % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes1);  % Assuming UIAxes is the name of your UIAxes component
            app.imageR = image;
        end

        % Button pushed function: addNoiseButton
        function addNoiseButton_Callback(app, event)
            % Get the selected noise type from the ListBox
            selectedNoiseType = app.TypesBruitListBox.Value;
            % Check if an image is loaded
            if isempty(app.imageR)
                msgbox('Veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Apply the selected noise type
            switch selectedNoiseType
                case 'Gaussian'
                    noisyImage = imnoise(app.imageR, 'gaussian', 0, 0.01);
                case 'Salt & Pepper'
                    noisyImage = imnoise(app.imageR, 'salt & pepper', 0.05);
                case 'Speckle'
                    noisyImage = imnoise(app.imageR, 'speckle', 0.04);
                case 'Poisson'
                    noisyImage = imnoise(app.imageR, 'poisson');
                otherwise
                    % Handle unsupported noise types or add more cases as needed
                    msgbox('Unsupported noise type selected.', 'Error', 'error');
                    return;
            end
            % Display the noisy image or store it in app.imgR as needed
            app.noisyImageStored = noisyImage;
            imshow(app.noisyImageStored, 'parent', app.UIAxes2);
        end

        % Button pushed function: FiltregaussienButton
        function gaussianFilterButton_Callback(app, event)
             % Check if a noisy image is provided
            if isempty(app.noisyImageStored)
                msgbox('Aucune image bruitée fournie.', 'Error', 'error');
                return;
            end  
            grayscaleImage = rgb2gray(app.noisyImageStored);
            % Apply Gaussian filter to eliminate noise
            filteredImage = imgaussfilt(grayscaleImage, 2);  % You can adjust the standard deviation (sigma) as needed
            % Display the filtered image in UIAxes3
            imshow(filteredImage, 'parent', app.UIAxes3);
            % Calculate PSNR
            psnrValue = psnr(grayscaleImage, filteredImage);
            % Display the PSNR value in the EditField
            app.PSNREditField.Value = double(psnrValue);
        end

        % Button pushed function: FiltreMINButton
        function minFilterButton_Callback(app, event)
            % Check if a noisy image is provided
            if isempty(app.noisyImageStored)
                msgbox('Aucune image bruitée fournie.', 'Error', 'error');
                return;
            end
            grayscaleImage = rgb2gray(app.noisyImageStored);
            % Apply minimum filter to the noisy image
            filterSize = 3; % Adjust the filter size as needed
            filteredImage = ordfilt2(grayscaleImage, 1, ones(filterSize));
            % Display the filtered image in UIAxes3
            imshow(filteredImage, 'parent', app.UIAxes3);
            % Calculate PSNR
            psnrValue = psnr(grayscaleImage, filteredImage);
            % Display the PSNR value in the EditField
            app.PSNREditField.Value = double(psnrValue);
        end

        % Button pushed function: FiltreMAXButton
        function maxFilterButton_Callback(app, event)
             % Check if a noisy image is provided
            if isempty(app.noisyImageStored)
                msgbox('Aucune image bruitée fournie.', 'Error', 'error');
                return;
            end
            % Convert the noisy image to grayscale
            grayscaleImage = rgb2gray(app.noisyImageStored);
            % Apply maximum filter to the noisy image
            filterSize = 3; % Adjust the filter size as needed
            filteredImage = ordfilt2(grayscaleImage, filterSize^2, ones(filterSize));
            % Display the filtered image in UIAxes3
            imshow(filteredImage, 'parent', app.UIAxes3);
            % Calculate PSNR
            psnrValue = psnr(grayscaleImage, filteredImage);
            app.PSNREditField.Value = double(psnrValue);
        end

        % Button pushed function: FiltremoyenneurButton
        function meanFilterButton_Callback(app, event)
            % Check if a noisy image is provided
            if isempty(app.noisyImageStored)
                msgbox('Aucune image bruitée fournie.', 'Error', 'error');
                return;
            end
            grayscaleImage = rgb2gray(app.noisyImageStored);
            % Define a mean filter kernel
            filterSize = 3; % Adjust the filter size as needed
            meanFilterKernel = ones(filterSize) / filterSize^2;
            % Apply the mean filter to the noisy image
            filteredImage = imfilter(grayscaleImage, meanFilterKernel, 'symmetric');
            % Display the filtered image in UIAxes3
            imshow(filteredImage, 'parent', app.UIAxes3);
            % Calculate PSNR
            psnrValue = psnr(grayscaleImage, filteredImage);
            % Display the PSNR value in the EditField
            app.PSNREditField.Value = double(psnrValue);
        end

        % Button pushed function: FiltreMedianButton
        function medianFilterButton_Callback(app, event)
            % Check if a noisy image is provided
            if isempty(app.noisyImageStored)
                msgbox('Aucune image bruitée fournie.', 'Error', 'error');
                return;
            end
            grayscaleImage = rgb2gray(app.noisyImageStored);
            % Define the size of the neighborhood for the median filter
            filterSize = 3; % Adjust the filter size as needed
            % Apply the median filter to the noisy image
            filteredImage = medfilt2(grayscaleImage, [filterSize, filterSize]);
            % Display the filtered image in UIAxes3
            imshow(filteredImage, 'parent', app.UIAxes3);
            % Calculate PSNR
            psnrValue = psnr(grayscaleImage, filteredImage);
            % Display the PSNR value in the EditField
            app.PSNREditField.Value = double(psnrValue);
        end

        % Button pushed function: ClearButton
        function clear_images_Callback(app, event)
            % Reset the imgOriginal property to empty
            app.noisyImageStored = []; 
            app.imageR = [];
            % Clear all axes components
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            % Optionally, you may want to display a message to inform the user
            msgbox('Images effacées.', 'Info', 'info');
        end

        % Button pushed function: RetourButton
        function Return_Callback(app, event)
            app.App = app1;
            app.App.UIApplication.Visible = 'on';  % Show the UIFigure of the Traitement app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIRestauration = uifigure;
            app.UIRestauration.Name = 'Restauration';
            app.RstaurationdimagesLabel = uilabel(app.UIRestauration);
            app.RstaurationdimagesLabel.Text = 'Réstauration d''images';
            app.ImagesRGBLabel = uilabel(app.UIRestauration);
            app.ImagesRGBLabel.Text = 'Images RGB :';
            app.UploadimageButton = uibutton(app.UIRestauration, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @upload_image_Callback, true);
            app.UploadimageButton.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.UploadimageButton.Text = 'Upload image';
            app.FiltregaussienButton = uibutton(app.UIRestauration, 'push');
            app.FiltregaussienButton.ButtonPushedFcn = createCallbackFcn(app, @gaussianFilterButton_Callback, true);
            app.FiltregaussienButton.Text = 'Filtre gaussien';
            app.FiltreMedianButton = uibutton(app.UIRestauration, 'push');
            app.FiltreMedianButton.ButtonPushedFcn = createCallbackFcn(app, @medianFilterButton_Callback, true);
            app.FiltreMedianButton.Text = 'Filtre Median';
            app.FiltremoyenneurButton = uibutton(app.UIRestauration, 'push');
            app.FiltremoyenneurButton.ButtonPushedFcn = createCallbackFcn(app, @meanFilterButton_Callback, true);
            app.FiltremoyenneurButton.Text = 'Filtre moyenneur';
            app.FiltreMINButton = uibutton(app.UIRestauration, 'push');
            app.FiltreMINButton.ButtonPushedFcn = createCallbackFcn(app, @minFilterButton_Callback, true);
            app.FiltreMINButton.Text = 'Filtre MIN';
            app.FiltreMAXButton = uibutton(app.UIRestauration, 'push');
            app.FiltreMAXButton.ButtonPushedFcn = createCallbackFcn(app, @maxFilterButton_Callback, true);
            app.FiltreMAXButton.Text = 'Filtre MAX';
            app.PSNREditFieldLabel = uilabel(app.UIRestauration);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.UIRestauration, 'numeric');
            app.UIAxes1 = uiaxes(app.UIRestauration);
            title(app.UIAxes1, 'Image Originale')
            app.UIAxes1.XTick = [];
            app.UIAxes1.XTickLabel = {'[ ]'};
            app.UIAxes1.YTick = [];
            app.UIAxes1.YTickLabel = {'[ ]'};
            app.UIAxes3 = uiaxes(app.UIRestauration);
            title(app.UIAxes3, 'Résultat')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = {'['};
            app.UIAxes3.YTick = [];
            app.UIAxes2 = uiaxes(app.UIRestauration);
            title(app.UIAxes2, 'Image Bruitée')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'[ ]'};
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = {'[ ]'};
            app.TypesBruitListBoxLabel = uilabel(app.UIRestauration);
            app.TypesBruitListBoxLabel.Text = 'Types Bruit';
            app.TypesBruitListBox = uilistbox(app.UIRestauration);
            app.TypesBruitListBox.Items = {'Gaussian', 'Salt & Pepper', 'Poisson', 'Speckle'};
            app.TypesBruitListBox.Value = 'Gaussian';
            app.addNoiseButton = uibutton(app.UIRestauration, 'push');
            app.addNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @addNoiseButton_Callback, true);
            app.addNoiseButton.Text = 'Ajout Bruit';
            app.ClearButton = uibutton(app.UIRestauration, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear_images_Callback, true);
            app.ClearButton.Text = 'Clear';
            app.RetourButton = uibutton(app.UIRestauration, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @Return_Callback, true);
            app.RetourButton.Text = 'Retour';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Restauration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIRestauration)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIRestauration)
        end
    end
end