classdef Segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UISegmentation           matlab.ui.Figure
        SgmentationdimagesLabel  matlab.ui.control.Label
        UploadImageButton        matlab.ui.control.Button
        ApprocheContourLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UploadImageButton_2      matlab.ui.control.Button
        AppliquerButton_2        matlab.ui.control.Button
        ApprocheRgionLabel       matlab.ui.control.Label
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UtilisantLabel           matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
        AppliquerButton_3        matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        RetourButton             matlab.ui.control.Button
    end


    properties (Access = private)
        segImage % Description
        segImage2 % Description
        App % Description
    end


    methods (Access = private)

        % Button pushed function: UploadImageButton
        function upload_image_Callback(app, event)
             % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes);  % Assuming UIAxes is the name of your UIAxes component
            app.segImage = image;
        end

        % Button pushed function: UploadImageButton_2
        function upload2_image_Callback(app, event)
            % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes_3);  % Assuming UIAxes is the name of your UIAxes component
            app.segImage2 = image;
        end

        % Button pushed function: ClearButton
        function clear_images_Callback(app, event)
            % Reset the imgOriginal property to empty
            app.segImage = []; 
            app.segImage2 = [];
            % Clear all axes components
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            % Optionally, you may want to display a message to inform the user
            msgbox('Images effacées.', 'Info', 'info');
        end

        % Button pushed function: AppliquerButton_3
        function applyEdgeMethodButton_Callback(app, event)
            % Get the selected edge detection method from the ListBox
            selectedMethod = app.ListBox.Value;
        
            % Check if an image is loaded
            if isempty(app.segImage)
                msgbox('Veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            grayImage = rgb2gray(app.segImage);

            % Apply the selected edge detection method
            switch selectedMethod
                case 'Prewitt'
                    edgeImage = edge(grayImage, 'Prewitt');
                case 'Sobel'
                    edgeImage = edge(grayImage, 'Sobel');
                case 'Robert'
                    edgeImage = edge(grayImage, 'Roberts');
                otherwise
                    % Handle unsupported methods or add more cases as needed
                    msgbox('Unsupported edge detection method selected.', 'Error', 'error');
                    return;
            end
            % Display the overlayedImage 
            imshow(edgeImage, 'parent', app.UIAxes_2);
        end

        % Button pushed function: AppliquerButton_2
        function applyRegionBasedApproach(app, event)
            % Convert the image to grayscale if necessary
            if isempty(app.segImage2)
                msgbox('Veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            if size(app.segImage2, 3) == 3
                grayImage = rgb2gray(app.segImage2);
            else
                grayImage = app.segImage2;  % Use the correct variable name
            end
            %contrôle le seuil de division => à quel point une région est considérée comme suffisamment homogène pour être divisée en quatre sous-régions.
            splittingThreshold = 20; % Adjust as needed
            mergingThreshold = 5;    % Adjust as needed
            
            % Initialize the segmentation result with the original image
            segmentedRegion = grayImage;
            
            % Split and merge the image
            [rows, cols] = size(segmentedRegion);
            
            % Split the image region into four quadrants if the splitting criterion is met
            if numel(unique(segmentedRegion)) > 1 && std2(segmentedRegion) > splittingThreshold
                % Split the region into four quadrants
                quadrant1 = segmentedRegion(1:rows/2, 1:cols/2);
                quadrant2 = segmentedRegion(1:rows/2, cols/2+1:end);
                quadrant3 = segmentedRegion(rows/2+1:end, 1:cols/2);
                quadrant4 = segmentedRegion(rows/2+1:end, cols/2+1:end);
                
                % Recursive split for each quadrant
                % Quadrant 1
                if numel(unique(quadrant1)) > 1 && std2(quadrant1) > splittingThreshold
                    quadrant1 = mean2(quadrant1) * ones(size(quadrant1));
                end
                % Quadrant 2
                if numel(unique(quadrant2)) > 1 && std2(quadrant2) > splittingThreshold
                    quadrant2 = mean2(quadrant2) * ones(size(quadrant2));
                end
                % Quadrant 3
                if numel(unique(quadrant3)) > 1 && std2(quadrant3) > splittingThreshold
                    quadrant3 = mean2(quadrant3) * ones(size(quadrant3));
                end
                % Quadrant 4
                if numel(unique(quadrant4)) > 1 && std2(quadrant4) > splittingThreshold
                    quadrant4 = mean2(quadrant4) * ones(size(quadrant4));
                end
        
                % Combine the four quadrants
                segmentedRegion(1:rows/2, 1:cols/2) = quadrant1;
                segmentedRegion(1:rows/2, cols/2+1:end) = quadrant2;
                segmentedRegion(rows/2+1:end, 1:cols/2) = quadrant3;
                segmentedRegion(rows/2+1:end, cols/2+1:end) = quadrant4;
            elseif numel(unique(segmentedRegion)) > 1 && std2(segmentedRegion) < mergingThreshold
                % Merge adjacent regions based on the merging criterion
                % Merge the region into a single value
                segmentedRegion = mean2(segmentedRegion) * ones(rows, cols);
            end
            
            % Display the result
            imshow(segmentedRegion, 'Parent', app.UIAxes_4);
            pause(2);
            msgbox('Merci d''avoir pris le temps d''explorer les méandres de mon application. Vos retours sont la clef qui ouvre les portes de l''amélioration continue.', 'Retour d''exécution', 'help');

        end

        % Button pushed function: RetourButton
        function Return_Callback(app, event)
            app.App = app1;
            app.App.UIApplication.Visible = 'on';  % Show the UIFigure of the Traitement app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UISegmentation = uifigure;
            app.UISegmentation.Name = 'Segmentation';
            app.SgmentationdimagesLabel = uilabel(app.UISegmentation);
            app.SgmentationdimagesLabel.Text = 'Ségmentation d''images';
            app.UploadImageButton = uibutton(app.UISegmentation, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @upload_image_Callback, true);
            app.UploadImageButton.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.UploadImageButton.Text = 'Upload Image';
            app.ApprocheContourLabel = uilabel(app.UISegmentation);
            app.ApprocheContourLabel.Text = 'Approche Contour :';
            app.UIAxes = uiaxes(app.UISegmentation);
            title(app.UIAxes, 'Image originale')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes_2 = uiaxes(app.UISegmentation);
            title(app.UIAxes_2, 'Détection de contour')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[ ]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {'[ ]'};
            app.UploadImageButton_2 = uibutton(app.UISegmentation, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @upload2_image_Callback, true);
            app.UploadImageButton_2.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.AppliquerButton_2 = uibutton(app.UISegmentation, 'push');
            app.AppliquerButton_2.ButtonPushedFcn = createCallbackFcn(app, @applyRegionBasedApproach, true);
            app.AppliquerButton_2.Text = 'Appliquer';
            app.ApprocheRgionLabel = uilabel(app.UISegmentation);
            app.ApprocheRgionLabel.Text = 'Approche Région : ';
            app.UIAxes_3 = uiaxes(app.UISegmentation);
            title(app.UIAxes_3, 'Image originale')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = {'[ ]'};
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = {'[ ]'};
            app.UIAxes_4 = uiaxes(app.UISegmentation);
            title(app.UIAxes_4, 'Approche région Résultat')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = {'[ ]'};
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = {'[ ]'};
            app.UtilisantLabel = uilabel(app.UISegmentation);
            app.UtilisantLabel.Text = 'Utilisant';
            app.ListBox = uilistbox(app.UISegmentation);
            app.ListBox.Items = {'Prewitt', 'Sobel', 'Robert'};
            app.ListBox.Value = 'Prewitt';
            app.AppliquerButton_3 = uibutton(app.UISegmentation, 'push');
            app.AppliquerButton_3.ButtonPushedFcn = createCallbackFcn(app, @applyEdgeMethodButton_Callback, true);
            app.AppliquerButton_3.Text = 'Appliquer';
            app.ClearButton = uibutton(app.UISegmentation, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear_images_Callback, true);
            app.ClearButton.Text = 'Clear';
            app.RetourButton = uibutton(app.UISegmentation, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @Return_Callback, true);
            app.RetourButton.Text = 'Retour';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Segmentation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UISegmentation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UISegmentation)
        end
    end
end