classdef Transformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UITransformation            matlab.ui.Figure
        TypesdetransformationLabel  matlab.ui.control.Label
        UploadImageButton           matlab.ui.control.Button
        NoiretblancButton           matlab.ui.control.Button
        NiveaudegrisButton          matlab.ui.control.Button
        ComplmentaireButton         matlab.ui.control.Button
        SymtrieHorizButton          matlab.ui.control.Button
        SymtrieVertButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        TransformationdimagesLabel  matlab.ui.control.Label
        OperationsurlesimagesLabel  matlab.ui.control.Label
        Uploadimage1Button          matlab.ui.control.Button
        Uploadimage2Button          matlab.ui.control.Button
        SommeButton                 matlab.ui.control.Button
        SoustractionButton          matlab.ui.control.Button
        OUButton                    matlab.ui.control.Button
        ETButton                    matlab.ui.control.Button
        SymtriecentreButton         matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2_3                   matlab.ui.control.UIAxes
        ClearButton                 matlab.ui.control.Button
        RetourButton                matlab.ui.control.Button
    end


    properties (Access = private)
        imgOriginal % Description
        img1 % Description
        img2 % Description
        App
    end


    methods (Access = private)

        % Button pushed function: UploadImageButton
        function upload_image_Callback(app, event)
            % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes);  % Assuming UIAxes is the name of your UIAxes component
            app.imgOriginal = image;
        end

        % Button pushed function: NiveaudegrisButton
        function gris_scale_Callback(app, event)
            % Ensure that the original image is loaded before converting
            if isempty(app.imgOriginal)
                % Inform the user to upload an image first
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            % Convert the original image to grayscale
            gray = rgb2gray(app.imgOriginal);
            % Display the grayscale image in the axes component named 'axes_2'
            imshow(gray, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: NoiretblancButton
        function blackAndWhite_scale_Callback(app, event)
            % Ensure that the original image is loaded before converting
            if isempty(app.imgOriginal)
                % Inform the user to upload an image first
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
           % Convert the grayscale image to binary (black and white)
            threshold = 0.5;  % Adjust the threshold as needed
            binaryImage = imbinarize(rgb2gray(app.imgOriginal), threshold);
            % Display the black and white image in the axes component named 'axes_2'
            imshow(binaryImage, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: SymtrieVertButton
        function symetrie_verticale_Callback(app, event)
            % Ensure that the original image is loaded before converting
            if isempty(app.imgOriginal)
            % Inform the user to upload an image first
            msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
            return;
            end
            % Perform vertical symmetry on the original image
            symmetricImage = flip(app.imgOriginal, 2);
            % Display the symmetric image in the axes component named 'axes_2'
            imshow(symmetricImage, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: SymtrieHorizButton
        function symetrie_Horizontale_Callback(app, event)
            % Ensure that the original image is loaded before converting
            if isempty(app.imgOriginal)
            % Inform the user to upload an image first
            msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
            return;
            end
            % Perform vertical symmetry on the original image
            symmetricImage = flip(app.imgOriginal, 1);
            % Display the symmetric image in the axes component named 'axes_2'
            imshow(symmetricImage, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: ComplmentaireButton
        function Complementary_Callback(app, event)
             % Ensure that the original image is loaded before converting
            if isempty(app.imgOriginal)
            % Inform the user to upload an image first
            msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
            return;
            end 
            complementaryImage = imcomplement(app.imgOriginal);
            % Display the complementary image in the axes component named 'axes_2'
            imshow(complementaryImage, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: SymtriecentreButton
        function central_symmetry_Callback(app, event)
            % Ensure that the original image is loaded before applying symmetry
            if isempty(app.imgOriginal)
                % Inform the user to upload an image first
                msgbox('veuillez d''abord télécharger une image.', 'Error', 'error');
                return;
            end
            
            % Get the size of the original image
            [rows, cols, ~] = size(app.imgOriginal);
            
            % Compute the center of the image
            centerRow = round(rows / 2);
            centerCol = round(cols / 2);
            
            % Perform central symmetry by rotating the original image by 180 degrees around its center
            symmetricImage = imrotate(app.imgOriginal, 180, 'crop');
            
            % Display the symmetric image in the axes component named 'axes_2'
            imshow(symmetricImage, 'parent', app.UIAxes_2);  % Assuming 'UIAxes_2' is the name of the second axes component
        end

        % Button pushed function: ClearButton
        function clear_images_Callback(app, event)
            % Reset the imgOriginal property to empty
            app.imgOriginal = []; 
            app.img1 = [];
            app.img2 = [];
            % Clear all axes components
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            % Optionally, you may want to display a message to inform the user
            msgbox('Images effacées.', 'Info', 'info');
        end

        % Button pushed function: Uploadimage1Button
        function upload_image1_Callback(app, event)
             % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            image=imread(filename);
            end
            imshow(image, 'Parent', app.UIAxes2);  % Assuming UIAxes is the name of your UIAxes component
            app.img1 = image;
        end

        % Button pushed function: Uploadimage2Button
        function upload_image2_Callback(app, event)
             % Allow the user to choose an image file
            [filename,pathname] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.jif';'*.svg'});
            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname,0)
                disp('user pressed cancel'); % User canceled, do nothing
            else
            filename=strcat(pathname,filename);
            img=imread(filename);
            end
            imshow(img, 'Parent', app.UIAxes2_2);  % Assuming UIAxes is the name of your UIAxes component
            app.img2 = img;
        end

        % Button pushed function: SommeButton
        function somme_Callback(app, event)
             % Ensure that both images are loaded before performing the sum
            if isempty(app.img1) || isempty(app.img2)
                % Inform the user to upload both images first
                msgbox('Veuillez télécharger les deux images avant d''effectuer la somme.', 'Error', 'error');
                return;
            end
            % Get the grayscale representations of the images
            try
                ng1 = rgb2gray(app.img1);
            catch
                ng1 = app.img1;
            end
            try
                ng2 = rgb2gray(app.img2);
            catch
                ng2 = app.img2;
            end
            % Resize the images if they have different sizes
            try
                ng2 = imresize(ng2, size(ng1));
            catch
                ng1 = imresize(ng1, size(ng2));
            end
            % Perform the sum of the two images
            sumImage = ng1 + ng2;
            % Display the sum image in the axes component named 'axes2_3'
            imshow(sumImage, 'parent', app.UIAxes2_3);  % Assuming 'UIAxes2_3' is the name of the second axes component
        end

        % Button pushed function: SoustractionButton
        function soustraction_Callback(app, event)
                    % Ensure that both images are loaded before performing the subtraction
            if isempty(app.img1) || isempty(app.img2)
                % Inform the user to upload both images first
                msgbox('Veuillez télécharger les deux images avant d''effectuer la soustraction.', 'Error', 'error');
                return;
            end
            % Get the grayscale representations of the images
            try
                ng1 = rgb2gray(app.img1);
            catch
                ng1 = app.img1;
            end
            try
                ng2 = rgb2gray(app.img2);
            catch
                ng2 = app.img2;
            end
            % Resize the images if they have different sizes
            try
                ng2 = imresize(ng2, size(ng1));
            catch
                ng1 = imresize(ng1, size(ng2));
            end
            % Perform the subtraction of the two images
            subtractionImage = ng1 - ng2;
            % Display the subtraction image in the axes component named 'axes2_3'
            imshow(subtractionImage, 'parent', app.UIAxes2_3);  % Assuming 'UIAxes2_3' is the name of the second axes component
        end

        % Button pushed function: OUButton
        function logical_OR_Callback(app, event)
                     % Ensure that both images are loaded before performing the logical OR
            if isempty(app.img1) || isempty(app.img2)
                % Inform the user to upload both images first
                msgbox('Veuillez télécharger les deux images avant d''effectuer le OU logique', 'Error', 'error');
                return;
            end
        
            % Convert images to grayscale
            try
                ng1 = rgb2gray(app.img1);
            catch
                ng1 = app.img1;
            end
        
            try
                ng2 = rgb2gray(app.img2);
            catch
                ng2 = app.img2;
            end
        
            % Resize the images if they have different sizes
            try
                ng2 = imresize(ng2, size(ng1));
            catch
                ng1 = imresize(ng1, size(ng2));
            end
        
            % Perform logical OR on the two images
            logicalOrImage = ng1 | ng2;
        
            % Display the result in the axes component named 'UIAxes2_3'
            imshow(logicalOrImage, 'Parent', app.UIAxes2_3);
        
            % Display unique pixel values of the result
            disp('Pixel values of logicalOrImage:');
            disp(unique(logicalOrImage));
        end

        % Button pushed function: ETButton
        function logical_AND_Callback(app, event)
            % Ensure that both images are loaded before performing the logical AND
            if isempty(app.img1) || isempty(app.img2)
                % Inform the user to upload both images first
                msgbox('Veuillez télécharger les deux images avant d''effectuer le ET logique', 'Error', 'error');
                return;
            end
            % Convert images to grayscale
            try
                ng1 = rgb2gray(app.img1);
            catch
                ng1 = app.img1;
            end
            
            try
                ng2 = rgb2gray(app.img2);
            catch
                ng2 = app.img2;
            end
            % Resize the images if they have different sizes
            try
                ng2 = imresize(ng2, size(ng1));
            catch
                 ng1 = imresize(ng1, size(ng2));
            end
            % Display some debugging information
            disp('Pixel values of ng1:');
            disp(unique(ng1));
            disp('Pixel values of ng2:');
            disp(unique(ng2));
            % Perform logical AND on the two images
            logicalAndImage = ng1 & ng2;
            % Display the result in the axes component named 'UIAxes2_3'
            imshow(logicalAndImage, 'parent', app.UIAxes2_3);
            % Display debugging information about the result
            disp('Pixel values of logicalAndImage:');
            disp(unique(logicalAndImage));
        end

        % Button pushed function: RetourButton
        function Return_Callback(app, event)
            app.App = app1;
            app.App.UIApplication.Visible = 'on';  % Show the UIFigure of the Traitement app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UITransformation = uifigure;
            app.UITransformation.Name = 'Transformation';
            app.TypesdetransformationLabel = uilabel(app.UITransformation);
            app.TypesdetransformationLabel.Text = 'Types de transformation :';
            app.UploadImageButton = uibutton(app.UITransformation, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @upload_image_Callback, true);
            app.UploadImageButton.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.UploadImageButton.Text = 'Upload Image';
            app.NoiretblancButton = uibutton(app.UITransformation, 'push');
            app.NoiretblancButton.ButtonPushedFcn = createCallbackFcn(app, @blackAndWhite_scale_Callback, true);
            app.NoiretblancButton.Icon = 'MatlabLogo.png';
            app.NoiretblancButton.Text = 'Noir et blanc';
            app.NiveaudegrisButton = uibutton(app.UITransformation, 'push');
            app.NiveaudegrisButton.ButtonPushedFcn = createCallbackFcn(app, @gris_scale_Callback, true);
            app.NiveaudegrisButton.Text = 'Niveau de gris ';
            app.ComplmentaireButton = uibutton(app.UITransformation, 'push');
            app.ComplmentaireButton.ButtonPushedFcn = createCallbackFcn(app, @Complementary_Callback, true);
            app.ComplmentaireButton.Text = 'Complémentaire';
            app.SymtrieHorizButton = uibutton(app.UITransformation, 'push');
            app.SymtrieHorizButton.ButtonPushedFcn = createCallbackFcn(app, @symetrie_Horizontale_Callback, true);
            app.SymtrieHorizButton.Text = 'Symétrie Horiz';
            app.SymtrieVertButton = uibutton(app.UITransformation, 'push');
            app.SymtrieVertButton.ButtonPushedFcn = createCallbackFcn(app, @symetrie_verticale_Callback, true);
            app.SymtrieVertButton.Text = 'Symétrie Vert';
            app.UIAxes = uiaxes(app.UITransformation);
            title(app.UIAxes, 'Image originale')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UITransformation);
            title(app.UIAxes_2, 'Transformation Result')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.TransformationdimagesLabel = uilabel(app.UITransformation);
            app.TransformationdimagesLabel.Text = 'Transformation d''images';
            app.OperationsurlesimagesLabel = uilabel(app.UITransformation);
            app.OperationsurlesimagesLabel.Text = 'Operation sur les images :';
            app.Uploadimage1Button = uibutton(app.UITransformation, 'push');
            app.Uploadimage1Button.ButtonPushedFcn = createCallbackFcn(app, @upload_image1_Callback, true);
            app.Uploadimage1Button.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.Uploadimage1Button.Text = 'Upload image1';
            app.Uploadimage2Button = uibutton(app.UITransformation, 'push');
            app.Uploadimage2Button.ButtonPushedFcn = createCallbackFcn(app, @upload_image2_Callback, true);
            app.Uploadimage2Button.Icon = 'pngtree-upload-icon-isolated-on-background-png-image_1813629-removebg-preview.png';
            app.Uploadimage2Button.Text = 'Upload image2';
            app.SommeButton = uibutton(app.UITransformation, 'push');
            app.SommeButton.ButtonPushedFcn = createCallbackFcn(app, @somme_Callback, true);
            app.SommeButton.Text = 'Somme';
            app.SoustractionButton = uibutton(app.UITransformation, 'push');
            app.SoustractionButton.ButtonPushedFcn = createCallbackFcn(app, @soustraction_Callback, true);
            app.SoustractionButton.Text = 'Soustraction';
            app.OUButton = uibutton(app.UITransformation, 'push');
            app.OUButton.ButtonPushedFcn = createCallbackFcn(app, @logical_OR_Callback, true);
            app.OUButton.Text = 'OU';
            app.ETButton = uibutton(app.UITransformation, 'push');
            app.ETButton.ButtonPushedFcn = createCallbackFcn(app, @logical_AND_Callback, true);
            app.ETButton.Text = 'ET';
            app.SymtriecentreButton = uibutton(app.UITransformation, 'push');
            app.SymtriecentreButton.ButtonPushedFcn = createCallbackFcn(app, @central_symmetry_Callback, true);
            app.SymtriecentreButton.Text = 'Symétrie centre';
            app.UIAxes2 = uiaxes(app.UITransformation);
            title(app.UIAxes2, 'Image 1')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes2_2 = uiaxes(app.UITransformation);
            title(app.UIAxes2_2, 'Image 2')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.XTickLabel = '';
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.YTickLabel = '';
            app.UIAxes2_3 = uiaxes(app.UITransformation);
            title(app.UIAxes2_3, 'Transformation Result')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.XTickLabel = '';
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.YTickLabel = '';
            app.ClearButton = uibutton(app.UITransformation, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clear_images_Callback, true);
            app.ClearButton.Text = 'Clear';
            app.RetourButton = uibutton(app.UITransformation, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @Return_Callback, true);
            app.RetourButton.Text = 'Retour';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Transformation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UITransformation)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UITransformation)
        end
    end
end