classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIApplication                   matlab.ui.Figure
        TraitementdimagesLabel          matlab.ui.control.Label
        MenudapplicationLabel           matlab.ui.control.Label
        TransformationdimageButton      matlab.ui.control.Button
        RestaurationdimageButton        matlab.ui.control.Button
        RhaussementdimageButton         matlab.ui.control.Button
        SgmentationdimageButton         matlab.ui.control.Button
        Button                          matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        ChaouachiEya3INREVLabel         matlab.ui.control.Label
        AnneUniversitaire20232024Label  matlab.ui.control.Label
    end


    properties (Access = private)
        Transform % Description
        Restaure % Description
        Rehausse % Description
        segmente % Description
    end

    methods (Access = private)
    % Code that executes after component creation
    end


    methods (Access = private)

        % Button pushed function: TransformationdimageButton
        function TransformationdimageButtonPushed(app, event)
    % Open the Transform window when the app is created
            app.Transform = Transformation;
            app.Transform.UITransformation.Visible = 'on';  % Show the UIFigure of the Transformation app
        end

        % Button pushed function: RestaurationdimageButton
        function RestaurationimageButtonPushed(app, event)
            % Open the Transform window when the app is created
            app.Restaure = Restauration;
            app.Restaure.UIRestauration.Visible = 'on';  % Show the UIFigure of the Restauration app
        end

        % Button pushed function: SgmentationdimageButton
        function segmentationimageButtonPushed(app, event)
            % Open the Transform window when the app is created
            app.segmente = Segmentation;
            app.segmente.UISegmentation.Visible = 'on';  % Show the UIFigure of the Segmentation app
        end

        % Button pushed function: RhaussementdimageButton
        function RehaussementimageButtonPushed(app, event)
            % Open the Transform window when the app is created
            app.Rehausse = Rehaussement;
            app.Rehausse.UIRehaussement.Visible = 'on';  % Show the UIFigure of the Rehaussement app
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIApplication = uifigure;
            app.UIApplication.Name = 'Application';
            app.TraitementdimagesLabel = uilabel(app.UIApplication);
            app.TraitementdimagesLabel.Text = 'Traitement d''images';
            app.MenudapplicationLabel = uilabel(app.UIApplication);
            app.MenudapplicationLabel.Text = 'Menu d''application ';
            app.TransformationdimageButton = uibutton(app.UIApplication, 'push');
            app.TransformationdimageButton.ButtonPushedFcn = createCallbackFcn(app, @TransformationdimageButtonPushed, true);
            app.TransformationdimageButton.Text = 'Transformation d''image';
            app.RestaurationdimageButton = uibutton(app.UIApplication, 'push');
            app.RestaurationdimageButton.ButtonPushedFcn = createCallbackFcn(app, @RestaurationimageButtonPushed, true);
            app.RestaurationdimageButton.Text = 'Restauration d''image';
            app.RhaussementdimageButton = uibutton(app.UIApplication, 'push');
            app.RhaussementdimageButton.ButtonPushedFcn = createCallbackFcn(app, @RehaussementimageButtonPushed, true);
            app.RhaussementdimageButton.Text = 'Réhaussement d''image';
            app.SgmentationdimageButton = uibutton(app.UIApplication, 'push');
            app.SgmentationdimageButton.ButtonPushedFcn = createCallbackFcn(app, @segmentationimageButtonPushed, true);
            app.SgmentationdimageButton.Text = 'Ségmentation d''image';
            app.Button = uibutton(app.UIApplication, 'state');
            app.Button.Icon = 'logo.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIApplication, 'state');
            app.Button_2.Icon = 'matlab-mathworks-numerical-analysis-computational-science-programming-language-removebg-preview.png';
            app.Button_2.Text = '';
            app.ChaouachiEya3INREVLabel = uilabel(app.UIApplication);
            app.ChaouachiEya3INREVLabel.Text = 'Chaouachi Eya 3INREV';
            app.AnneUniversitaire20232024Label = uilabel(app.UIApplication);
            app.AnneUniversitaire20232024Label.Text = 'Année Universitaire 2023-2024';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIApplication)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIApplication)
        end
    end
end